classdef Detector_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DetectedTonesTextArea       matlab.ui.control.TextArea
        DetectedTonesTextAreaLabel  matlab.ui.control.Label
        DetectsoundButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        toneAll=[];
        fs = 8000;

    end
    
    methods (Access = public)
        
        function Decoded_output = DTMFdetector(~, signal)
            Decoded_output=[];
            a697=[1 -2*cos(2*pi*18/205) 1];
            a770=[1 -2*cos(2*pi*20/205) 1];
            a852=[1 -2*cos(2*pi*22/205) 1];
            a941=[1 -2*cos(2*pi*24/205) 1];
            a1209=[1 -2*cos(2*pi*31/205) 1];
            a1336=[1 -2*cos(2*pi*34/205) 1];
            a1477=[1 -2*cos(2*pi*38/205) 1];
            a1633=[1 -2*cos(2*pi*42/205) 1];
            %output=[];
            for ii=0:(length(signal)/1421-1)
                tone=signal(1+1421*ii:1421*(ii+1));
                tone=tone(401:end);
    
                yDTMF=[tone 0];
                y697=filter(1,a697,yDTMF);
                y770=filter(1,a770,yDTMF);
                y852=filter(1,a852,yDTMF);
                y941=filter(1,a941,yDTMF);
                y1209=filter(1,a1209,yDTMF);
                y1336=filter(1,a1336,yDTMF);
                y1477=filter(1,a1477,yDTMF);
                y1633=filter(1,a1633,yDTMF);

                m(1)=sqrt(y697(206)^2+y697(205)^2-2*cos(2*pi*18/205)*y697(206)*y697(205));
                m(2)=sqrt(y770(206)^2+y770(205)^2-2*cos(2*pi*20/205)*y770(206)*y770(205));
                m(3)=sqrt(y852(206)^2+y852(205)^2-2*cos(2*pi*22/205)*y852(206)*y852(205));
                m(4)=sqrt(y941(206)^2+y941(205)^2-2*cos(2*pi*24/205)*y941(206)*y941(205));
                m(5)=sqrt(y1209(206)^2+y1209(205)^2-2*cos(2*pi*31/205)*y1209(206)*y1209(205));
                m(6)=sqrt(y1336(206)^2+y1336(205)^2-2*cos(2*pi*34/205)*y1336(206)*y1336(205));
                m(7)=sqrt(y1477(206)^2+y1477(205)^2-2*cos(2*pi*38/205)*y1477(206)*y1477(205));
                m(8)=sqrt(y1633(206)^2+y1633(205)^2-2*cos(2*pi*42/205)*y1633(206)*y1633(205));
                m=2*m/205;
                th=sum(m)/4;  % based on empirical measurement
                f=[697 770 852 941 1209 1336 1477 1633];
                f1=[0  4000];
                th=[th th];
    
                idx=m>th(1);
                Determination=f(idx);
                switch Determination(1)
                    case {697}
                        switch Determination(2)
                            case {1209}
                                output='1 ';
                            case {1336}
                                output='2 ';
                            case {1477}
                                output='3 ';
                            case {1633}
                                output='A ';
                        end
                    case {770}
                        switch Determination(2)
                            case {1209}
                                output='4 ';
                            case {1336}
                                output='5 ';
                            case {1477}
                                output='6 ';
                            case {1633}
                                output='B ';
                        end
                    case {852}
                        switch Determination(2)
                            case {1209}
                                output='7 ';
                            case {1336}
                                output='8 ';
                            case {1477}
                                output='9 ';
                            case {1633}
                                output='C ';
                        end
                    case {941}
                        switch Determination(2)
                            case {1209}
                                output='* ';
                            case {1336}
                                output='0 ';
                            case {1477}
                                output='# ';
                            case {1633}
                                output='D ';
                        end
                end
                Decoded_output = [Decoded_output, output];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DetectsoundButton
        function DetectsoundButtonPushed(app, event)
            %t=(0:length(app.toneAll)-1)/app.fs;
            app.toneAll = audioread('C:\Users\Admin\OneDrive\BK21-25\23-24-Sem5\digital signal processing\thy\combine\toneAll.wav');
            app.toneAll = (app.toneAll') * 2;
            t=(0:length(app.toneAll)-1)/app.fs;
            plot(app.UIAxes, t, app.toneAll)
            sound = app.toneAll;
            soundsc(sound, 8000)
            Decoded_output = DTMFdetector(app, sound);
            app.DetectedTonesTextArea.Value = Decoded_output;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal received')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.DetectsoundButton = uibutton(app.UIFigure, 'push');
            app.DetectsoundButton.ButtonPushedFcn = createCallbackFcn(app, @DetectsoundButtonPushed, true);
            app.DetectsoundButton.Text = 'Detect sound';
            app.DetectedTonesTextAreaLabel = uilabel(app.UIFigure);
            app.DetectedTonesTextAreaLabel.Text = 'Detected Tones';
            app.DetectedTonesTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Detector_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end