classdef Generator_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ClearButton              matlab.ui.control.Button
        CombinetonesButton       matlab.ui.control.Button
        InputtonesTextArea       matlab.ui.control.TextArea
        InputtonesTextAreaLabel  matlab.ui.control.Label
        DButton                  matlab.ui.control.Button
        Button_hash              matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        Button_star              matlab.ui.control.Button
        CButton                  matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        BButton                  matlab.ui.control.Button
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        AButton                  matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button_1                 matlab.ui.control.Button
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        keyNames = {}; 
        toneAll = []; % Description
        fs = 8000;
    end
    
    methods (Access = public)
        function tone = DTMFgenerator(app, f1, f2)
            t=(0:1:204*5)/app.fs;
            x=zeros(1,length(t));
            x(1)=1;
            tone = filter([0 sin(2*pi*f1/app.fs) ],[1 -2*cos(2*pi*f1/app.fs) 1],x) + filter([0 sin(2*pi*f1/app.fs) ],[1 -2*cos(2*pi*f2/app.fs) 1],x);
            app.toneAll = [app.toneAll,zeros(1,400),tone];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            y1 = DTMFgenerator (app, 697, 1209);
            soundsc(y1, app.fs)
            plot(app.UIAxes, y1)
            app.keyNames = [app.keyNames, num2str(1)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y1);
            Ak=2*abs(fft(y1))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            y2 = DTMFgenerator (app, 697, 1336);
            soundsc(y2, app.fs)
            plot(app.UIAxes, y2)
            app.keyNames = [app.keyNames, num2str(2)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y2);
            Ak=2*abs(fft(y2))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            y3 = DTMFgenerator (app, 697, 1477);
            soundsc(y3, app.fs)
            plot(app.UIAxes, y3)
            app.keyNames = [app.keyNames, num2str(3)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y3);
            Ak=2*abs(fft(y3))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            yA = DTMFgenerator (app, 697, 1633);
            soundsc(yA, app.fs)
            plot(app.UIAxes, yA)
            app.keyNames = [app.keyNames, 'A'];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(yA);
            Ak=2*abs(fft(yA))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            y4 = DTMFgenerator (app, 770, 1209);
            soundsc(y4, app.fs)
            plot(app.UIAxes, y4)
            app.keyNames = [app.keyNames, num2str(4)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y4);
            Ak=2*abs(fft(y4))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            y5 = DTMFgenerator (app, 770, 1336);
            soundsc(y5, app.fs)
            plot(app.UIAxes, y5)
            app.keyNames = [app.keyNames, num2str(5)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y5);
            Ak=2*abs(fft(y5))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            y6 = DTMFgenerator (app, 770, 1477);
            soundsc(y6, app.fs)
            plot(app.UIAxes, y6)
            app.keyNames = [app.keyNames, num2str(6)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y6);
            Ak=2*abs(fft(y6))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            yB = DTMFgenerator (app, 770, 1633);
            soundsc(yB, app.fs)
            plot(app.UIAxes, yB)
            app.keyNames = [app.keyNames, 'B'];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(yB);
            Ak=2*abs(fft(yB))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            y7 = DTMFgenerator (app, 852, 1209);
            soundsc(y7, app.fs)
            plot(app.UIAxes, y7)
            app.keyNames = [app.keyNames, num2str(7)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y7);
            Ak=2*abs(fft(y7))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            y8 = DTMFgenerator (app, 852, 1336);
            soundsc(y8, app.fs)
            plot(app.UIAxes, y8)
            app.keyNames = [app.keyNames, num2str(8)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y8);
            Ak=2*abs(fft(y8))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            y9 = DTMFgenerator (app, 852, 1477);
            soundsc(y9, app.fs)
            plot(app.UIAxes, y9)
            app.keyNames = [app.keyNames, num2str(9)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y9);
            Ak=2*abs(fft(y9))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            yC = DTMFgenerator (app, 852, 1633);
            soundsc(yC, app.fs)
            plot(app.UIAxes, yC)
            app.keyNames = [app.keyNames, 'C'];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(yC);
            Ak=2*abs(fft(yC))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_star
        function Button_starPushed(app, event)
            ystar = DTMFgenerator (app, 941, 1209);
            soundsc(ystar, app.fs)
            plot(app.UIAxes, ystar)
            app.keyNames = [app.keyNames, '*'];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(ystar);
            Ak=2*abs(fft(ystar))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            y0 = DTMFgenerator (app, 941, 1336);
            soundsc(y0, app.fs)
            plot(app.UIAxes, y0)
            app.keyNames = [app.keyNames, num2str(0)];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(y0);
            Ak=2*abs(fft(y0))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: Button_hash
        function Button_hashPushed(app, event)
            yhash = DTMFgenerator (app, 941, 1477);
            soundsc(yhash, app.fs)
            plot(app.UIAxes, yhash)
            app.keyNames = [app.keyNames, '#'];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(yhash);
            Ak=2*abs(fft(yhash))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            yD = DTMFgenerator (app, 941, 1633);
            soundsc(yD, app.fs)
            plot(app.UIAxes, yD)
            app.keyNames = [app.keyNames, 'D'];
            app.InputtonesTextArea.Value = strjoin(app.keyNames, ' ');
            N = length(yD);
            Ak=2*abs(fft(yD))/N;
            Ak(1)=Ak(1)/2;
            f=(0:1:(N-1)/2)*app.fs/N;
            plot(app.UIAxes_2, f, Ak(1:(N+1)/2))
        end

        % Button pushed function: CombinetonesButton
        function CombinetonesButtonPushed(app, event)
            t=(0:length(app.toneAll)-1)/app.fs;
            filename = 'C:\Users\Admin\OneDrive\BK21-25\23-24-Sem5\digital signal processing\thy\combine\toneAll.wav';
            audiowrite(filename,(app.toneAll')/2,8000);
            sound = audioread('C:\Users\Admin\OneDrive\BK21-25\23-24-Sem5\digital signal processing\thy\combine\toneAll.wav');
            plot(app.UIAxes, t, sound)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.InputtonesTextArea.Value = '';
            app.toneAll = [];
            app.keyNames = {};
            cla(app.UIAxes)
            cla(app.UIAxes_2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal of tone')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Spectrum of tone')
            xlabel(app.UIAxes_2, 'Frequency')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.Button_star = uibutton(app.UIFigure, 'push');
            app.Button_star.ButtonPushedFcn = createCallbackFcn(app, @Button_starPushed, true);
            app.Button_star.Text = '*';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '0';
            app.Button_hash = uibutton(app.UIFigure, 'push');
            app.Button_hash.ButtonPushedFcn = createCallbackFcn(app, @Button_hashPushed, true);
            app.Button_hash.Text = '#';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.InputtonesTextAreaLabel = uilabel(app.UIFigure);
            app.InputtonesTextAreaLabel.Text = 'Input tones';
            app.InputtonesTextArea = uitextarea(app.UIFigure);
            app.CombinetonesButton = uibutton(app.UIFigure, 'push');
            app.CombinetonesButton.ButtonPushedFcn = createCallbackFcn(app, @CombinetonesButtonPushed, true);
            app.CombinetonesButton.Text = 'Combine tones';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Generator_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end