classdef LinearGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InputaEditFieldLabel_4  matlab.ui.control.Label
        InputaEditFieldLabel_3  matlab.ui.control.Label
        InputaEditFieldLabel_2  matlab.ui.control.Label
        Inputx0EditField        matlab.ui.control.NumericEditField
        Inputx0EditFieldLabel   matlab.ui.control.Label
        InputnEditField         matlab.ui.control.NumericEditField
        InputnEditFieldLabel    matlab.ui.control.Label
        InputmEditField         matlab.ui.control.NumericEditField
        InputmEditFieldLabel    matlab.ui.control.Label
        InputbEditField         matlab.ui.control.NumericEditField
        InputbEditFieldLabel    matlab.ui.control.Label
        InputaEditField         matlab.ui.control.NumericEditField
        InputaEditFieldLabel    matlab.ui.control.Label
        GenerateButton          matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        LinearCongruentialSequenceGeneratorLabel  matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            a = app.InputaEditField.Value;
            b = app.InputbEditField.Value;
            m = app.InputmEditField.Value;
            n = app.InputnEditField.Value;
            x0 = app.Inputx0EditField.Value;
            x = zeros(1, n);
            x(1)=x0;
            for i=2:n 
                x(i)=mod(a*x(i-1)+b,m); 
            end     
            app.UITable.ColumnWidth = {'auto', '1x'};
           % Assuming you have a Table component named "ResultTable" in your app
            columnNames = {'Index', 'Value'};  % Column names for the table
            data = [1:n; x]';  % Data for the table, assuming n is the number of results and x is the sequence
            app.UITable.ColumnName = columnNames;
            app.UITable.Data = data;
            app.LinearCongruentialSequenceGeneratorLabel.FontSize = 24; % Set the font size to 14
        end

        % Value changed function: InputaEditField
        function InputaEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '370202336_1307544796574741_4165768701386380883_n.png');
            app.LinearCongruentialSequenceGeneratorLabel = uilabel(app.UIFigure);
            app.LinearCongruentialSequenceGeneratorLabel.Text = 'Linear Congruential Sequence Generator';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Index'; 'Number'};
            app.UITable.RowName = {};
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.InputaEditFieldLabel = uilabel(app.UIFigure);
            app.InputaEditFieldLabel.Text = 'Input a ';
            app.InputaEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputaEditField.ValueChangedFcn = createCallbackFcn(app, @InputaEditFieldValueChanged, true);
            app.InputbEditFieldLabel = uilabel(app.UIFigure);
            app.InputbEditFieldLabel.Text = 'Input b';
            app.InputbEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputmEditFieldLabel = uilabel(app.UIFigure);
            app.InputmEditFieldLabel.Text = 'Input m';
            app.InputmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputnEditFieldLabel = uilabel(app.UIFigure);
            app.InputnEditFieldLabel.Text = 'Input n';
            app.InputnEditField = uieditfield(app.UIFigure, 'numeric');
            app.Inputx0EditFieldLabel = uilabel(app.UIFigure);
            app.Inputx0EditFieldLabel.Text = 'Input x0';
            app.Inputx0EditField = uieditfield(app.UIFigure, 'numeric');
            app.InputaEditFieldLabel_2 = uilabel(app.UIFigure);
            app.InputaEditFieldLabel_2.Text = 'The multiplier';
            app.InputaEditFieldLabel_3 = uilabel(app.UIFigure);
            app.InputaEditFieldLabel_3.Text = 'The increment';
            app.InputaEditFieldLabel_4 = uilabel(app.UIFigure);
            app.InputaEditFieldLabel_4.Text = 'The modulus';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LinearGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end