classdef WhiteNoiseGaussian < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GenerateButton  matlab.ui.control.Button
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes_4        matlab.ui.control.UIAxes
        UIAxes_5        matlab.ui.control.UIAxes
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)


        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            N = 1000; % Number of samples 
P = 1; % Mức điểm nhiễu (độ ồn) (dBW)
pow = -20; % Công suất tín hiệu nhiễu (dBW)<0 => nhỏ hơn p của tín hiệu chính
fc=0.2;
% Generate White Noise Gaussian
noise_signal = wgn(N, P, pow);
sound(noise_signal, 4100);
plot(app.UIAxes,noise_signal);
 %High pass
num1=fir1(N,fc,'high'); 
den1=[1]; 
fvtool(num1, den1); 
FILTER1 = filter(num1, den1, noise_signal); 
sound(FILTER1, 4100);
plot(app.UIAxes_3,FILTER1);
%Bandpass Filter 
f2=0.3;
f1=0.8; 
FILTER3=fir1(N, [f2 f1]); 
fvtool(FILTER3, 1)
output_signal = filter(FILTER3, 1, noise_signal);
sound(output_signal, 4100);
plot(app.UIAxes_5,output_signal);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'White Noise Gaussian')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Output of Highpass Filter')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Output of Bandpass Filter')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Bandpass Filter')
            xlabel(app.UIAxes_4, 'Normalized Frequency (x𝝿rad/sample)')
            ylabel(app.UIAxes_4, 'Magnitude (dB)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Highpass Filter')
            xlabel(app.UIAxes_2, 'Normalized Frequency (x𝝿rad/sample)')
            ylabel(app.UIAxes_2, 'Magnitude (dB)')
            zlabel(app.UIAxes_2, 'Z')
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WhiteNoiseGaussian

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end